classdef ProMakroS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainSystemControlsPanel         matlab.ui.container.Panel
        ProjectorPanel                  matlab.ui.container.Panel
        plainButton                     matlab.ui.control.Button
        fringesButton                   matlab.ui.control.Button
        whiteButton                     matlab.ui.control.Button
        ProjectorStatusLabel            matlab.ui.control.Label
        ProjectorStatusLamp             matlab.ui.control.Lamp
        blueButton                      matlab.ui.control.StateButton
        greenButton                     matlab.ui.control.StateButton
        redButton                       matlab.ui.control.StateButton
        MeasurementPanel                matlab.ui.container.Panel
        ExportPointcloudButton          matlab.ui.control.Button
        StartMeasurementButton          matlab.ui.control.Button
        CameraSettingsPanel             matlab.ui.container.Panel
        CameraStatusLabel               matlab.ui.control.Label
        CameraStatusLamp                matlab.ui.control.Lamp
        ExposureTimemsSpinner           matlab.ui.control.Spinner
        ExposureTimemsSpinnerLabel      matlab.ui.control.Label
        StageControlPanel               matlab.ui.container.Panel
        StageStatusLamp                 matlab.ui.control.Lamp
        StageStatusLabel                matlab.ui.control.Label
        StepSizemmDropDown              matlab.ui.control.DropDown
        StepSizemmDropDownLabel         matlab.ui.control.Label
        ComPortDropDown                 matlab.ui.control.DropDown
        ComPortDropDownLabel            matlab.ui.control.Label
        PositionGauge                   matlab.ui.control.LinearGauge
        PositionGaugeLabel              matlab.ui.control.Label
        ZButtonDown                     matlab.ui.control.Button
        ZButtonUp                       matlab.ui.control.Button
        amgopticsLabel                  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LiveImageTab                    matlab.ui.container.Tab
        SnapShotButton                  matlab.ui.control.Button
        LiveImageAxes                   matlab.ui.control.UIAxes
        MeasurementTab                  matlab.ui.container.Tab
        ResolutionSlider                matlab.ui.control.Slider
        ResolutionSliderLabel           matlab.ui.control.Label
        ROIToolButton                   matlab.ui.control.StateButton
        RestoreViewButton               matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        PanToolButton                   matlab.ui.control.StateButton
        DistanceToolButton              matlab.ui.control.StateButton
        YZButton                        matlab.ui.control.Button
        XZButton                        matlab.ui.control.Button
        XYButton                        matlab.ui.control.Button
        SliceSlider                     matlab.ui.control.Slider
        SliceSliderLabel                matlab.ui.control.Label
        ZoomSlider                      matlab.ui.control.Slider
        ZoomSliderLabel                 matlab.ui.control.Label
        ElevationSlider                 matlab.ui.control.Slider
        ElevationLabel                  matlab.ui.control.Label
        AzimuthSlider                   matlab.ui.control.Slider
        AzimuthLabel                    matlab.ui.control.Label
        MeasurementAxes                 matlab.ui.control.UIAxes
        CameraCalibrationTab            matlab.ui.container.Tab
        UndistortButton                 matlab.ui.control.StateButton
        SelectedCalibrationStatsLabel   matlab.ui.control.Label
        CalibrationResultsPanel         matlab.ui.container.Panel
        ExtrinsicMatrixLabel            matlab.ui.control.Label
        CalibrationStatsLabel           matlab.ui.control.Label
        KameraMatrixLabel               matlab.ui.control.Label
        RecordnewCheckerBoardImagesPanel  matlab.ui.container.Panel
        SnapButton                      matlab.ui.control.Button
        PatchSizeEditField              matlab.ui.control.NumericEditField
        PatchSizeinmmEditField_2Label   matlab.ui.control.Label
        PathEditField_2                 matlab.ui.control.EditField
        PathEditField_2Label            matlab.ui.control.Label
        SelectSaveDirButton             matlab.ui.control.Button
        StartCameraButton               matlab.ui.control.Button
        LoadCheckerBoardImagesPanel     matlab.ui.container.Panel
        CalibrateCameraButton           matlab.ui.control.Button
        LoadedListBox                   matlab.ui.control.ListBox
        LoadedListBoxLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        LoadImagesButton                matlab.ui.control.Button
        LoadedCalibrationImagesPanel    matlab.ui.container.Panel
        CalibrationImagesAxes           matlab.ui.control.UIAxes
        SystemCalibrationTab            matlab.ui.container.Tab
        GeneratenewPhasetoHeightCalibrationFilePanel  matlab.ui.container.Panel
        Usethesamecheckerboardasforcameracalibration2Label_2  matlab.ui.control.Label
        StartPhaseCalibrationButton     matlab.ui.control.Button
        EvaluateTelecentricityErrorPanel  matlab.ui.container.Panel
        Usethesamecheckerboardasforcameracalibration2Label  matlab.ui.control.Label
        TotaltelecentricityerrorEditField  matlab.ui.control.EditField
        TotaltelecentricityerrorEditFieldLabel  matlab.ui.control.Label
        StartProcessButton              matlab.ui.control.Button
        PhaseCalibrationAxesC2          matlab.ui.control.UIAxes
        PhaseCalibrationAxesC1          matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        CameraIntrinsicParametersPanel  matlab.ui.container.Panel
        q2EditField                     matlab.ui.control.NumericEditField
        q2EditFieldLabel                matlab.ui.control.Label
        q1EditField                     matlab.ui.control.NumericEditField
        q1EditFieldLabel                matlab.ui.control.Label
        p2EditField                     matlab.ui.control.NumericEditField
        p2EditFieldLabel                matlab.ui.control.Label
        p1EditField                     matlab.ui.control.NumericEditField
        p1EditFieldLabel                matlab.ui.control.Label
        LensdistortioncoefficientsLabel  matlab.ui.control.Label
        LensdistortioncenterLabel       matlab.ui.control.Label
        LensskewfactorLabel             matlab.ui.control.Label
        LensscalingfactorsLabel         matlab.ui.control.Label
        betaEditField                   matlab.ui.control.NumericEditField
        betaEditFieldLabel              matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        k1EditField                     matlab.ui.control.NumericEditField
        k1EditFieldLabel                matlab.ui.control.Label
        v0EditField                     matlab.ui.control.NumericEditField
        v0EditFieldLabel                matlab.ui.control.Label
        u0EditField                     matlab.ui.control.NumericEditField
        u0EditFieldLabel                matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        gammaEditFieldLabel             matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        HeterodyneFringePatternPanel    matlab.ui.container.Panel
        projectiondelaysEditField       matlab.ui.control.NumericEditField
        projectiondelaysEditFieldLabel  matlab.ui.control.Label
        lambda_3EditField               matlab.ui.control.NumericEditField
        lambda_3EditFieldLabel          matlab.ui.control.Label
        lambda_2EditField               matlab.ui.control.NumericEditField
        lambda_2EditFieldLabel          matlab.ui.control.Label
        lambda_1EditField               matlab.ui.control.NumericEditField
        lambda_1Label                   matlab.ui.control.Label
        SystemLogTab                    matlab.ui.container.Tab
        LogTextArea                     matlab.ui.control.TextArea
        HelpTab                         matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
        % GUI
        defaultBkgColor = [1 1 1]*0.149;
        defaultHighlightColor = [0.06,1.00,1.00];
        projectorFigure
        projectorFigureAxes
        livePreviewTimerObj
        % initialAxesXLimit
        viewCenter
        initialViewRange
        previewHandle
        panHandle
        dcm

        % Visualization
        randomIndices
        
        % Stage Serial Connection
        % serialObject
        freePorts
    end
    
    properties (Access = public)
        % GUI
        projectorImageHandle
        scatterHandle

        % path and folders
        rootDir
        tmpFolder_telecentricity

        % Projector
        projectorSize

        % Camera
        camera
        cameraSource
        cameraSize
        measurementMode
        recentFrame

        % Camera calibration
        calibrationImagePath = ""; % Description
        calib_struct
        param_vec
        pts_w
        pts_i
        mean_reproError
        batch_img_pts
        reproError
        images
        imgs_correct
        selectedImageIndex

        % Stage
        stage

        % System settings
        systemSettings

        % System Calibration
        phaseToHeigthFit

        % 3D-Measurement data
        measurementResult
        measurementResultWCS
        points
        cursorData
    end
  
    methods (Access = private)
        %% System Log
        function logMessage(app, message)
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            % Append the new message to the existing text
            app.LogTextArea.Value = [app.LogTextArea.Value; {[char(timestamp) ' >> ' message]}];
        end
        
        %% Allocate Projector Window
        function allocateProjectorWindow(app)
            % Get screen size information of all connected monitors
            logMessage(app, 'looking for second monitor...');
            screenInfo = get(0, "MonitorPositions");
            % Check the number of connected monitors
            numMonitors = size(screenInfo, 1);
            if numMonitors > 1
                logMessage(app, 'a second monitor is connected.');
                % Retrieve the resolution of the second monitor
                secondMonitorPosition = screenInfo(2, :);
                logMessage(app, ['second monitor resolution: ', num2str(secondMonitorPosition(3)), 'x', num2str(secondMonitorPosition(4)), '.']);
            else
                logMessage(app, 'no second monitor detected.');
                msg = 'No Second Monitor (Beamer) connected.';
                uiconfirm(app.UIFigure, msg, 'Error', 'Options', {'Ok'}, 'Icon', 'error');
                return;
            end

            app.projectorSize = screenInfo(2,3:4);
            app.projectorFigure = figure(1);
            app.projectorFigure.Position = screenInfo(2, :);
            colormap gray
            app.projectorFigureAxes = axes(app.projectorFigure,"Units","normalized","Position",[0 0 1 1]);
            app.projectorFigureAxes.Toolbar.Visible = 'off'; % Disable toolbar
            WindowAPI(app.projectorFigure, 'position', 'full')
            WindowAPI(app.projectorFigure, 'clip');
            app.projectorFigureAxes.XLim = [0, app.projectorSize(1)];
            app.projectorFigureAxes.YLim = [0, app.projectorSize(2)];
            app.projectorFigureAxes.Interactions = []; % Disable interactivity
            % Show white Frame 
            frame = uint8(ones(flip(app.projectorSize)) * 255);
            app.projectorImageHandle = imshow(frame, 'Parent', app.projectorFigureAxes); 
            
            % update GUI
            app.whiteButton.Enable = "on";
            app.redButton.Enable = "on";
            app.greenButton.Enable = "on";
            app.blueButton.Enable = "on";
            app.fringesButton.Enable = "on";
            app.plainButton.Enable = "on";
            app.ProjectorStatusLamp.Color = 'green';
            app.ProjectorStatusLabel.Text = 'Online';

            logMessage(app, 'projector window setup successful.');
        end

        %% Project Test Image
        function projectTestImage(app, rgb)
            % Display the grayscale image in the axes
            % profile on
            % profile off
            % profile viewer
            rgb = uint8(rgb)*255;
            frame = uint8(ones(flip(app.projectorSize)));
            rgbFrame = cat(3, frame*rgb(1), frame*rgb(2), frame*rgb(3));
            set(app.projectorImageHandle, 'CData', rgbFrame);
            drawnow;
        end

        %% Setup Camera
        function result = setupCamera(app)
            delete(imaqfind);
            logMessage(app, 'trying to connect to camera...');
            try
                disp('starting camera 0')
                % Initialize the camera object with appropriate format
                app.camera = videoinput('gige', 1, 'Mono8');
                % Access the camera's source properties
                app.cameraSource = app.camera.Source;
                % Set PacketSize to a typical value for GigE (Jumbo Frames)
                % Note: 15000 is too large; maximum Jumbo Frame size is usually around 9000 bytes
                app.cameraSource.PacketSize = 9000;
                % Set the number of frames to capture per trigger
                app.camera.FramesPerTrigger = 1;
                % Set the camera to continuously capture frames
                app.camera.TriggerRepeat = Inf;
                % Configure the trigger to start immediately
                triggerconfig(app.camera, 'immediate');
                % Set trigger properties (corrected to avoid conflicts)
                % GigE cameras often use hardware triggers, but here it's set to software for immediate use
                % Not all cameras support TriggerSource and TriggerSelector; adjust based on camera specs
                % Commented out as these may cause issues if not supported
                % s.TriggerSelector = 'FrameStart';
                % s.TriggerSource = 'Software';
                % s.TriggerActivation = 'RisingEdge';
                app.cameraSource.TriggerMode = 'off'; % Ensure the trigger mode is set to off for software triggering
                % Specify a constant exposure time for each frame
                app.cameraSource.ExposureMode = 'Timed';
                app.cameraSource.ExposureTime = 20000; % Adjust based on camera's supported range (in microseconds)
                % Set the frame rate for acquisition
                app.cameraSource.AcquisitionFrameRate = 10;

                % Define the callback function
                app.camera.FramesAcquiredFcn = @(src, event)frameAcquiredCallback(app, src, event);
                app.camera.FramesAcquiredFcnCount = 1;  % Call the function for each acquired frame

                % app.camera = webcam();
                % hImage = image(app.LiveImageAxes, zeros(200, 300, 1, 'uint8'));
                % preview(app.camera,hImage);
                % [width, height] = strtok(app.camera.Resolution, 'x');
                % app.cameraSize = [str2double(width) str2double(strtok(height, 'x'))]; %
                app.cameraSize = app.camera.VideoResolution;

                app.LiveImageAxes.XLim = [0, app.cameraSize(1) + 0];
                app.LiveImageAxes.YLim = [0, app.cameraSize(2) + 0];
                app.LiveImageAxes.XTick = [];
                app.LiveImageAxes.YTick = [];
                app.LiveImageAxes.DataAspectRatio = [1, 1, 1];
                app.LiveImageAxes.PlotBoxAspectRatio = [app.cameraSize(1), app.cameraSize(2), 1];
                app.LiveImageAxes.XColor = 'none';
                app.LiveImageAxes.YColor = 'none';
                app.LiveImageAxes.Title.String = '';
                
                % Start the live preview timer
                disp('starting camera')
                app.measurementMode = false;
                start(app.camera);
                % pause(1)
                disp('camera started')

                % Update GUI elements
                % start(app.livePreviewTimerObj);
                app.CameraStatusLamp.Color = 'green';
                app.CameraStatusLabel.Text  = 'Online';
                 % Enable Start Measurement button
                app.StartMeasurementButton.Enable = "on";
                logMessage(app, 'camera setup successful.');
            catch
                app.camera = [];
                msg = 'unable to connect to camera.';
                logMessage(app, msg);
                uiconfirm(app.UIFigure, msg, 'Error', 'Options', {'Ok'}, 'Icon', 'error');
                result = -1;
            end
        end
        %% Define the callback function for Camera event
        function frameAcquiredCallback(app, ~, ~)
            % disp(app.camera.FramesAvailable)
            % tic
            numFrames = app.camera.FramesAvailable;
            if numFrames > 0
                app.recentFrame = peekdata(app.camera, 1);
                % Display the grayscale image in the axes
                if ~app.measurementMode
                    imshow(app.recentFrame, 'Parent', app.LiveImageAxes);
                    app.LiveImageAxes.XLim = [0, app.cameraSize(1)];
                    app.LiveImageAxes.YLim = [0, app.cameraSize(2)];
                end
                % app.LiveImageAxes.Toolbar.Visible = 'off'; % Disable toolbar
                % app.LiveImageAxes.Interactions = []; % Disable interactivity
            end    
            % Clear the data buffer to keep memory usage low
            flushdata(app.camera); 
            % toc
        end
        %% Function to process each frame
        function processLiveImage(app)
            % Get the current frame
            % frame = snapshot(app.camera);
            % Convert to gray
            % grayFrame = rgb2gray(frame);
            
            grayFrame = peekdata(app.camera,1);
            binaryMask = zeros(size(grayFrame,1), size(grayFrame,2));
            
            % Specify contrast threshold
            cThreshold = 0.8;
            % Define the green color and transparency level
            greenColor = [0, 255, 0]; % Pure green color
            alpha = 0.3; % Transparency level (0 = fully transparent, 1 = fully opaque)
            
            % Divide into sub areas
            steps = 20;
            patch_size = fix(size(grayFrame)/steps);
            % Iterate ofer each sub area
            for kk = 1:steps
                for ii = 1:steps
                    % extract Line
                    rowPointer = kk * patch_size(1)/2;
                    line_1 = mean(grayFrame(rowPointer:rowPointer+3,(ii-1)*patch_size(2)+1:(ii)*patch_size(2)),1);
                    % Calculate contrast within sub area
                    line_1_min = min(line_1);
                    line_1_max = max(line_1);
                    contrast = (line_1_max - line_1_min)/(line_1_max + line_1_min);
                    % Mark sub area if contrast is good enough
                    if (contrast > cThreshold)
                        % colorize Patch green
                        binaryMask(1+(kk-1)*patch_size(1):kk*patch_size(1),(ii-1)*patch_size(2)+1:(ii)*patch_size(2)) = 1;
                    end
                end
            end
            
            % Convert the grayscale image to an RGB image
            rgbImage = cat(3, grayFrame, grayFrame, grayFrame);

            % Create the transparent green overlay
            overlayImage = rgbImage;
            % binaryMask = double(originalImage);
            for i = 1:3 % Iterate over the color channels (R, G, B)
                overlayImage(:,:,i) = uint8(double(rgbImage(:,:,i)) .* (1 - alpha * binaryMask) + greenColor(i) * alpha * binaryMask);
            end
            
            % Display the grayscale image in the axes
            imshow(overlayImage, 'Parent', app.LiveImageAxes);
            app.LiveImageAxes.XLim = [0, app.cameraSize(1)];
            app.LiveImageAxes.YLim = [0, app.cameraSize(2)];
            app.LiveImageAxes.Toolbar.Visible = 'off'; % Disable toolbar
            app.LiveImageAxes.Interactions = []; % Disable interactivity
        end
        
        %% Camera Calibration
        function calibrateCamera(app)
            % Display a modal dialog indicating calculations are running
            logMessage(app, 'starting camera calibration calculations...');
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Calculating', 'Message', 'Calculations are in progress...');
            % 1. Read images into struct
            app.images = read_images([app.calibrationImagePath '\'],'.bmp');
            % 2. Find key Points
            patch_width = app.PatchSizeEditField.Value; % Kantenlänge in mm
            [~, ~, app.batch_img_pts, ~] = estimate_params(app.images, patch_width);
            dlg.Value = 1/5;
            % 3. Run calibration
            [app.calib_struct, target_struct]= calibrate(app.images, 3);
            dlg.Value = 4/5;
            % save
            saveFilePath = fullfile(app.rootDir, '\settings\calib_struct.mat');
            calib_struct_tmp = app.calib_struct;
            save(saveFilePath, 'calib_struct_tmp');
            % 4. %% Calculate Reprojection Error
            % worldPoints to ImagePoints: target struct --> image Coordinates
            % param_vec = [alpha, beta, gamma, u0, v0, k1, p1, p2, q1, q2, ra1, rb1, rc1, tx1,
            %   ty1, ..., ran, rbn, rcn, txn, tyn]
            app.param_vec = app.calib_struct.vector;
            app.pts_w = target_struct.w_coord;
            app.pts_i = batch_forward_model_v1(app.param_vec, app.pts_w, 'distort_plane', 'normal', 'distort_model', 'none');
            app.reproError = app.batch_img_pts - app.pts_i;
            app.mean_reproError = squeeze(mean(app.reproError,[1,2]));
            dlg.Value = 5/5;

            % 5. Correct for distortion
            app.imgs_correct = correctDistortion(app.images, app.calib_struct.intrinsics);
            

            % Show results
            % Construct the string with placeholders
            str = sprintf('$K=\\left(\\matrix{%.1f & %.1f & %.0f \\cr 0 & %.1f & %.0f \\cr 0 & 0 & 0}\\right)$', app.param_vec(1), app.param_vec(3), app.param_vec(4), app.param_vec(2), app.param_vec(5));
            app.KameraMatrixLabel.Text = str;

            str = sprintf('$\\bar{\\varepsilon} = %.2f$', mean(app.mean_reproError,'all'));
            app.CalibrationStatsLabel.Text = str; 
            app.CalibrationStatsLabel.Visible = "on";

            % Close the dialog when calculations are complete
            logMessage(app, 'calculations finished.');
            delete(dlg);
        end
        
        %% Update Calibration Image Preview
        function updateCalibrationImagesAxes(app, imageIndex)
            app.selectedImageIndex = imageIndex;
            % TODO: Check if points were found
            % selectedImage = imread(fullfile(app.calibrationImagePath, value));
            selectedImage = app.images{imageIndex};
            selectedImage = insertMarker(selectedImage, app.batch_img_pts(:,:,imageIndex), 'o', 'Color', 'red', 'Size', 20);
            selectedImage = insertText(selectedImage,app.batch_img_pts(1,1:2,imageIndex),1);
            imshow(selectedImage, 'Parent', app.CalibrationImagesAxes);   
            app.CalibrationImagesAxes.XLim = [0 size(selectedImage,2)];
            app.CalibrationImagesAxes.YLim = [0 size(selectedImage,1)];
            app.CalibrationImagesAxes.DataAspectRatio = [1 1 1];

            % Update Extrinsics
            vRot = app.param_vec(5*imageIndex+6:5*imageIndex+8);
            vRot = rad2deg(vRot);
            vT = app.param_vec(5*imageIndex+9:5*imageIndex+10);

            str = sprintf('$rot=\\left(\\matrix{%.1f \\cr %.1f \\cr %.1f}\\right),$   $t=\\left(\\matrix{%.1f \\cr %.1f \\cr 0}\\right)$', vRot(1), vRot(2), vRot(3), vT(1), vT(2));
            app.ExtrinsicMatrixLabel.Text = str;

            str = sprintf('Reprojection Error: $\\varepsilon_{i} = %.2f$', app.mean_reproError(imageIndex));
            app.SelectedCalibrationStatsLabel.Text = str; 

            %Reset Undistort Button
            app.UndistortButton.Value = false;
            app.UndistortButton.Visible = "on";
            app.SelectedCalibrationStatsLabel.Visible = "on";
            
        end
        
        %% Connect to stage
        function connectToStage(app)
            logMessage(app, 'trying to connect to stage...');
            app.stage.motionType = 0; % 0: G90=absolut, 1: G91 Motion = relative,
            app.stage.motionDistance = 0;
            
            % Some Serial communication ..
            
            % Enable all Controls
            app.StageStatusLamp.Color = [0 1 0];
            app.StageStatusLabel.Text = "Online";
            app.StepSizemmDropDown.Enable = "on";
            app.PositionGauge.Enable = "on";
            app.ZButtonDown.Enable = "on";
            app.ZButtonUp.Enable = "on"; 

            logMessage(app, 'unable to connect to stage.');
        end

        %%
        function sendDataToStageController(app)
            msg = ['<',...
                   num2str(app.stage.motionType) ','...
                   num2str(app.stage.motionDistance) ','... 
                   '>'];
            % app.StatusLamp.Color = rand(1,3);
            app.stage.serialObject.writeline(msg);
            % disp(msg)
        end

        %% Clean Up Timers and other stuff
        function onBeingDestroyed(app, ~, ~) 
            % Stop Camera Preview Timer if it still lives
            % if isvalid(app.livePreviewTimerObj)
            %     disp('The timer object ''app.livePreviewTimerObj'' is valid.');
            %     disp("I need to delete the Timer here...")
            %     stop(app.livePreviewTimerObj);
            %     delete(app.livePreviewTimerObj);
            % else
            %     disp('The timer object ''app.livePreviewTimerObj'' is not valid.');
            % end          
        end
        
        %% Show an exemplary 3d point cloud example 
        function showMeasurementExample(app)
            % Only if axes are empty
            if isempty(app.MeasurementAxes.Children)
                % Create a 3D surface plot
                [X, Y, Z] = peaks(100);
                Z = Z/10;
                app.measurementResultWCS.X = X;
                app.measurementResultWCS.Y = Y;
                app.measurementResultWCS.Z = Z;
                % surf(app.MeasurementAxes, X, Y, Z);
                app.scatterHandle = scatter3(app.MeasurementAxes, X(:), Y(:), Z(:), 1, Z(:), 'filled');
                axis(app.MeasurementAxes, 'tight');
                % Point Cloud
                % app.points = [X(:), Y(:), Z(:)];
                % app.ptCloud = pointCloud(app.points);
                % pcshow(app.ptCloud, 'Parent', app.MeasurementAxes, 'BackgroundColor',app.defaultBkgColor);
    
                % Setup Plot
                % app.MeasurementAxes.XLim
                % app.MeasurementAxes.YLim
                % app.MeasurementAxes.ZLim
                app.MeasurementAxes.DataAspectRatio = [1, 1, 1];
                % app.MeasurementAxes.Toolbar
                % app.MeasurementAxes.Box = "off";
                
                % save initial axes Limit
                % app.initialAxesXLimit = app.MeasurementAxes.XLim;
    
                updateMeasurementAxesInfo(app);
    
                % enable only pan
                app.panHandle.Enable = 'on';
                % Set initial view
                view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
            end
        end

        %% Show measuremnt Results
        function showMeasurementResults(app)
            disp('inside showMeasurementResults')
            % surf(app.MeasurementAxes, X, Y, Z);
            % transform Data
            K = [52.93 0 0; 0  52.768 0; 1224 1025 1];
            E = diag([1 1 1]);
            
            pt = [app.measurementResult.X(:), app.measurementResult.Y(:)];
            pts_wcs_tmp = backward_core(K,E,pt);
            app.measurementResultWCS.X = pts_wcs_tmp(:,1);
            app.measurementResultWCS.Y = pts_wcs_tmp(:,2);
            app.measurementResultWCS.Z = app.measurementResult.Z(:);

            % reduce point density for faster plotting
            idx_reduced = 2;
            
            cla(app.MeasurementAxes)
            app.scatterHandle = scatter3(app.MeasurementAxes, app.measurementResultWCS.X(1:idx_reduced:end), app.measurementResultWCS.Y(1:idx_reduced:end), app.measurementResultWCS.Z(1:idx_reduced:end), 1, app.measurementResultWCS.Z(1:idx_reduced:end), 'filled');
            axis(app.MeasurementAxes, 'tight');
            % Setup Plot
            % app.MeasurementAxes.XLim = [min(app.measurementResult.X(:)) max(app.measurementResult.X(:))];
            % app.MeasurementAxes.YLim = [min(app.measurementResult.Y(:)) max(app.measurementResult.Y(:))];
            % app.MeasurementAxes.ZLim = [min(app.measurementResult.Z(:)) max(app.measurementResult.Z(:))];

            app.MeasurementAxes.DataAspectRatio = [1, 1, 1];
            % enable only pan
            app.panHandle.Enable = 'on';
            % Set initial view
            view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
            
            updateMeasurementAxesInfo(app);
        end
        %% Slice Measurement Results
        function sliceMeasurementResults(app, sliceValue)
            % Select the subset of points
            min_Z = min(app.measurementResultWCS.Z(app.randomIndices), [], 'all');
            max_Z = max(app.measurementResultWCS.Z(app.randomIndices), [], 'all');
            threshold_value = (max_Z - min_Z) * sliceValue/100 + min_Z;
            Z_masked = app.measurementResultWCS.Z(app.randomIndices);
            Z_masked(app.measurementResultWCS.Z(app.randomIndices) > threshold_value) = NaN;  % Set values above threshold to NaN 
            % Plot the reduced scatter3 plot
            set(app.scatterHandle, 'XData', app.measurementResultWCS.X(app.randomIndices), 'YData', app.measurementResultWCS.Y(app.randomIndices), 'ZData', Z_masked(:), 'CData', Z_masked(:));
        end
        %% Update UIAxes Infos
        function updateMeasurementAxesInfo(app)
            % Update UIAxes mid point
            app.viewCenter.X = mean(app.MeasurementAxes.XLim);
            app.viewCenter.Y = mean(app.MeasurementAxes.YLim);
            app.viewCenter.Z = mean(app.MeasurementAxes.ZLim);
            app.initialViewRange.X = app.MeasurementAxes.XLim(2) - app.MeasurementAxes.XLim(1);
            app.initialViewRange.Y = app.MeasurementAxes.YLim(2) - app.MeasurementAxes.YLim(1);
            app.initialViewRange.Z = app.MeasurementAxes.ZLim(2) - app.MeasurementAxes.ZLim(1);
        end

        %% Load and Show Camera Calibration Images
        function loadAndShowCalibrationImages(app)
            bmpFiles = dir(fullfile(app.calibrationImagePath, '*.bmp'));
            tiffFiles = dir(fullfile(app.calibrationImagePath, '*.tiff'));
            allImageFiles = [bmpFiles; tiffFiles];
            % Check Directory
            if length(allImageFiles) < 1
                % Display a modal dialog indicating calculations are running
                msg = 'No *.bmp files found in this direcitry';
                uiconfirm(app.UIFigure, msg, 'Record Checker Board Images for Camera Calibration', 'Options', {'Ok' 'Cancel'}, 'Icon', 'error');
                return
            end
            list = {allImageFiles.name};
            app.LoadedListBox.Items = list;

            
            % Clear any old axes
            clearLoadedCalibrationImagesPanel(app);
            % Display all Images as preview
            for ii=1:length(allImageFiles)
                currentImage = imread(fullfile(app.calibrationImagePath, allImageFiles(ii).name));
                resizeFactor = 15;
                img_width = fix(size(currentImage,2)/resizeFactor);
                img_height = fix(size(currentImage,1)/resizeFactor);
                margin = 0;
                ax = uiaxes('Parent', app.LoadedCalibrationImagesPanel, ...
                            'Units', 'pixels', ...
                            'Position', [margin + (ii - 1) * (img_width+margin), margin, img_width, img_height] ...
                            );
                
                imshow(currentImage, 'Parent', ax, 'InitialMagnification','fit', Border='tight'); 
                ax.XLim = [0 size(currentImage,2)];
                ax.YLim = [0 size(currentImage,1)];
                ax.Toolbar.Visible = 'off'; % Disable toolbar
                % ax.BoxStyle = 'full';
                ax.Interactions = []; % Disable interactivity
                ax.Box = "off";
            end
        end

        %% Clear dynamically allocated UIAxes
        function clearLoadedCalibrationImagesPanel(app)
            % Get all children of the parent panel
            panelChildren = app.LoadedCalibrationImagesPanel.Children;
            
            % Iterate over each child
            for i = 1:length(panelChildren)
                % Check if the child is a uiaxes
                if isa(panelChildren(i), 'matlab.ui.control.UIAxes')
                    % Delete all children of the uiaxes
                    delete(panelChildren(i).Children);
                end
            end
        end

        %% Define custom data cursor mode update function
        function txt = dataCursorModeUpdateFcn(app, ~, event_obj)
            hold(app.MeasurementAxes, 'on');
            
            pos = get(event_obj, 'Position');
            % disp(pos)
            app.cursorData.selectedPoints(end+1, :) = pos; % Store the selected point
            % disp(app.cursorData.selectedPoints)
    
            plot3(app.MeasurementAxes, pos(1), pos(2), pos(3), 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'Tag', 'annotation');
            % check is two points are selected
            if size(app.cursorData.selectedPoints,1) > 1
                % perform measurements
                distance = sqrt(sum((app.cursorData.selectedPoints(2,:) - app.cursorData.selectedPoints(1,:)).^2));
                deltaXYZ = app.cursorData.selectedPoints(2,:) - app.cursorData.selectedPoints(1,:);
                point1 = app.cursorData.selectedPoints(1, :);
                point2 = app.cursorData.selectedPoints(2, :);
                plot3(app.MeasurementAxes,[point1(1) point2(1)], [point1(2) point2(2)], [point1(3) point2(3)], 'k-', 'LineWidth', 2, 'Color', 'r', 'Tag', 'annotation');
                
                % Save Result
                app.cursorData.distances(end+1, :) = [distance deltaXYZ pos];
                % reset selectedPoints
                app.cursorData.selectedPoints = [];
                % Text for Data Tip
                txt = {['d: ', num2str(distance, '%.3f')], ...
                       ['\Deltax: ', num2str(deltaXYZ(1), '%.3f')], ...
                       ['\Deltay: ', num2str(deltaXYZ(2), '%.3f')], ...
                       ['\Deltaz: ', num2str(deltaXYZ(3), '%.3f')]};
            else
                % add most recent data tip as text
                if size(app.cursorData.distances,1) > 0
                    txt = {['d: ', num2str(app.cursorData.distances(end, 1), '%.3f')], ...
                        ['\Deltax: ', num2str(app.cursorData.distances(end, 2), '%.3f')], ...
                        ['\Deltay: ', num2str(app.cursorData.distances(end, 3), '%.3f')], ...
                        ['\Deltaz: ', num2str(app.cursorData.distances(end, 4), '%.3f')]};
                    text(app.MeasurementAxes, app.cursorData.distances(end, 5), app.cursorData.distances(end, 6), app.cursorData.distances(end, 7), txt, ...
                        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'BackgroundColor', 'w', 'EdgeColor', 'k', 'Layer', 'front', ...
                        'Interpreter', 'tex', 'Tag', 'annotation');
                end
    
                % Hide the data tip popup by returning empty string
                txt = {''};
    
            end
            hold(app.MeasurementAxes, 'off');
        end

        %% Clear cursor annotation data
        function clearCursorData(app)
            app.cursorData.selectedPoints = [];
            app.cursorData.distances = [];
            annotationChildren = findobj(app.MeasurementAxes.Children, 'Tag', 'annotation');
            dataTips = findobj(app.MeasurementAxes.Children,'Type','DataTip');
            delete(annotationChildren);
            delete(dataTips);
        end

        %% Define a callback function for the plot interaction
        function measurementAxesPanCallback(app, ~, ~)
            % update viewCenter
            app.viewCenter.X = mean(app.MeasurementAxes.XLim);
            app.viewCenter.Y = mean(app.MeasurementAxes.YLim);
            app.viewCenter.Z = mean(app.MeasurementAxes.ZLim);
        end

    end
    
    methods (Access = public)
        
        %% Start camera
        function startCamera(app)
            if ~isempty(app.camera)
                start(app.camera)
            end
        end
        %% Stop camera
        function stopCamera(app)
            if ~isempty(app.camera)
                stop(app.camera)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 0. Path handling
            app.tmpFolder_telecentricity = '\tmp\telecentricity';
            % Get the full path of the current script or function
            fullPath = mfilename('fullpath');
            % Extract the directory part of the path
            [app.rootDir, ~, ~] = fileparts(fullPath);
            

            % 1. Initialize vital variables and objects
            % Timer object for continuous frame processing
            % app.livePreviewTimerObj = timer('ExecutionMode', 'fixedRate', 'Period', 0.5, 'TimerFcn', @(~, ~) processLiveImage(app));
            % app.livePreviewTimerObj.Name = 'livePreviewTimer';
            app.cursorData.selectedPoints = [];
            app.cursorData.distances = [];

            % Default Parameters
            app.cameraSize = [2448 2050];

            % listen to my ObjectBeingDestroyed event
            addlistener(app, 'ObjectBeingDestroyed', @app.onBeingDestroyed);
            logMessage(app, 'system started.');
            
            % 2. Setup GUI (e.g. hide inactive elements...)
            app.UIFigure.Name = 'ProMakroS Interface';
            app.UndistortButton.Visible = "off";
            app.SelectedCalibrationStatsLabel.Visible = "off";
            app.CalibrationStatsLabel.Visible = "off";
            app.SliceSlider.MajorTicks = 0:10:100;
            app.SliceSlider.MinorTicks = 0:5:100;  
            app.panHandle = pan(app.UIFigure);
            app.panHandle.ActionPostCallback = @(src, event) measurementAxesPanCallback(app, src, event);
            % app.panHandle.Enable = 'on';

            % Cursor
            app.dcm = datacursormode(app.UIFigure);
            % app.dcm.UpdateFcn = @(cursor, event_obj) dataCursorModeUpdateFcn(app, cursor, event_obj);
            app.MeasurementAxes.Toolbar.Visible = 'off'; % Disable toolbar
            

            % 3. Setup Beamer
            allocateProjectorWindow(app);
            
            % 4. Setup Camera
            setupCamera(app);

            % 5. load default parameter
            % Construct the path to the file
            configFilePath = fullfile(app.rootDir, '\settings\default_linFitResult.mat');
            CalibFile = load(configFilePath);
            app.phaseToHeigthFit = CalibFile.linFit;
            
            configFilePath = fullfile(app.rootDir, '\settings\default_calib_struct.mat');
            CalibFile = load(configFilePath);
            app.calib_struct = CalibFile.calib_struct_tmp;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % disp(selectedTab.Title)
            if selectedTab == app.LiveImageTab
                % Start Camera
                startCamera(app)
                % disp('Camera Started due to Tab change')
                % start(app.livePreviewTimerObj);
            else
                % Stop Livestream
                stopCamera(app)
                % disp('Camera Stopped due to Tab change')
                % stop(app.livePreviewTimerObj);
            end

            if selectedTab == app.MeasurementTab
                app.dcm.UpdateFcn = @(cursor, event_obj) dataCursorModeUpdateFcn(app, cursor, event_obj);
                showMeasurementExample(app);
            else
                app.dcm.UpdateFcn = [];
                clearCursorData(app);
            end
        end

        % Button pushed function: whiteButton
        function whiteButtonPushed(app, event)
            app.redButton.Value = 0;
            app.greenButton.Value = 0;
            app.blueButton.Value = 0;
            projectTestImage(app, [1 1 1]);
        end

        % Value changed function: redButton
        function redButtonValueChanged(app, event)
            projectTestImage(app, [app.redButton.Value app.greenButton.Value app.blueButton.Value]);
        end

        % Value changed function: greenButton
        function greenButtonValueChanged(app, event)
            projectTestImage(app, [app.redButton.Value app.greenButton.Value app.blueButton.Value]);
        end

        % Value changed function: blueButton
        function blueButtonValueChanged(app, event)
            projectTestImage(app, [app.redButton.Value app.greenButton.Value app.blueButton.Value]);
        end

        % Button pushed function: fringesButton
        function fringesButtonPushed(app, event)
            % Show fringes 
            lam_01 = 10;
            x_vec = (1:app.projectorSize(1));
            xy = ones(app.projectorSize(2),1) * x_vec;
            phi_01_true = 2*pi*(xy)/lam_01;
            I = cos(phi_01_true-2*pi*1/4);
            frame = uint8(rescale(I)*255);
            set(app.projectorImageHandle, 'CData', frame);
        end

        % Button pushed function: plainButton
        function plainButtonPushed(app, event)
            % Show white Frame 
            frame = uint8(ones(flip(app.projectorSize)) * 255);
            set(app.projectorImageHandle, 'CData', frame);
        end

        % Value changed function: ExposureTimemsSpinner
        function ExposureTimemsSpinnerValueChanged(app, event)
            value = app.ExposureTimemsSpinner.Value;
            app.cameraSource.ExposureTime = 1000*value;
            disp('Exposure set to')
            disp(1000*value)
        end

        % Drop down opening function: ComPortDropDown
        function ComPortDropDownOpening(app, event)
            % List all available Com Ports
            app.freePorts = serialportlist("available");
            app.ComPortDropDown.Items = app.freePorts;
        end

        % Clicked callback: ComPortDropDown
        function ComPortDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            % disp(app.freeports{item})
            % app.s = serialport(app.freeports{item},9600);
            if item ~= 0
                % try to connect to COM port
                try
                    app.stage.serialObject = serialport(app.freePorts{3},9600);
                    connectToStage(app);
                catch
                    uialert(app.UIFigure, ['Port ' app.freePorts{item} ' not available'] ,"Invalid COM Port");
                    return
                end                           
            end
        end

        % Button pushed function: ZButtonUp
        function ZButtonUpPushed(app, event)
            app.stage.motionType = 1; % 0: G90=absolut, 1: G91 Motion = relative,
            app.stage.motionDistance = str2double(app.StepSizemmDropDown.Value);
            % send movement request
            sendDataToStageController(app);
        end

        % Button pushed function: ZButtonDown
        function ZButtonDownPushed(app, event)
            app.stage.motionType = 1; % 0: G90=absolut, 1: G91 Motion = relative,
            app.stage.motionDistance = -1 * str2double(app.StepSizemmDropDown.Value);
            % send movement request
            sendDataToStageController(app);
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
            logMessage(app, 'measurement started...');
            % 1. Stop live preview
            % stop(app.livePreviewTimerObj);
            % make sure cam is stopped
            stopCamera(app)
            % 2. switch to measurement tab
            if (app.TabGroup.SelectedTab ~= app.MeasurementTab)
                app.TabGroup.SelectedTab = app.MeasurementTab;
                app.dcm.UpdateFcn = @(cursor, event_obj) dataCursorModeUpdateFcn(app, cursor, event_obj);
            end
            % 3. Perform measurement
            % 4. Show progress bar
            
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Measuring', 'Message', 'Measurement is in progress...');
            % start camera
            startCamera(app)
            pause(0.2)
            app.measurementMode = true;
            disp(size(app.recentFrame))
            perform_heterodyne_measurement_without_camera(app, dlg);
            % perform_heterodyne_measurement(app, dlg);
            % stop camera
            stopCamera(app)
            app.measurementMode = false;
            showMeasurementResults(app);

            % enable export button
            app.ExportPointcloudButton.Enable = "on";
            delete(dlg);
            logMessage(app, 'measurement done.');

        end

        % Button pushed function: ExportPointcloudButton
        function ExportPointcloudButtonPushed(app, event)
            x = app.measurementResultWCS.X;
            y = app.measurementResultWCS.Y;
            z = app.measurementResultWCS.Z;

            % % Find indices of valid (non-NaN) points
            % validIdx = ~isnan(x) & ~isnan(y) & ~isnan(z);
            % 
            % % Extract only the valid points
            % x_valid = x(validIdx);
            % y_valid = y(validIdx);
            % z_valid = z(validIdx);

            % Combine the data into a single matrix
            pointCloudData = [x, y, z];
            % Remove rows with NaN values
            pointCloudData(any(isnan(pointCloudData), 2), :) = [];

            ptCloud = pointCloud(pointCloudData(:, 1:3));

             % Open save as dialogue
            [file, path, filterindex] = uiputfile({'*.ply', 'PLY Files (*.ply)'; '*.csv', 'CSV Files (*.csv)'}, 'Save Point Cloud As');
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Export Pointcloud', 'Message', 'Exporting measurement data...');
            
            if isequal(file, 0)
                % User clicked cancel
                disp('User canceled save operation');
            else
                % Get the full file path
                filepath = fullfile(path, file);
                
                % Determine the file format based on the selected filter
                [~, ~, ext] = fileparts(filepath);
                
                switch ext
                    case '.ply'
                        % Write the point cloud to a PLY file
                        pcwrite(ptCloud, filepath);
                        disp(['Point cloud saved as ', filepath]);
                    case '.csv'
                        % Write the data to a CSV file
                        writematrix(pointCloudData, filepath);
                        disp(['Point cloud saved as ', filepath]);
                    otherwise
                        errordlg('Unsupported file format selected.');
                end
            end
            delete(dlg)
        end

        % Value changed function: PanToolButton
        function PanToolButtonValueChanged(app, event)
            value = app.PanToolButton.Value;
            % can only be switched on
            if value
                % reset DTM button
                set(app.dcm, 'Enable', 'off');
                app.DistanceToolButton.FontColor = [0 0 0];
                app.DistanceToolButton.Value = 0;
                
                % update button
                app.PanToolButton.FontColor = app.defaultHighlightColor;
                app.panHandle.Enable = 'on';
            else
                % % enable DTM
                % % reset Pan button
                % app.PanToolButton.FontColor = [0 0 0];
                % 
                % app.DistanceToolButton.FontColor = app.defaultHighlightColor;
                % app.DistanceToolButton.Value = 1;
                % set(app.dcm, 'Enable', 'on');

            end
            
        end

        % Value changed function: DistanceToolButton
        function DistanceToolButtonValueChanged(app, event)
            value = app.DistanceToolButton.Value;
            if value
                % enable DTM
                app.PanToolButton.FontColor = [0 0 0];
                app.PanToolButton.Value = 0;

                app.DistanceToolButton.FontColor = app.defaultHighlightColor;
                set(app.dcm, 'Enable', 'on');
            else
                % enable Pan Tool
                set(app.dcm, 'Enable', 'off');
                app.DistanceToolButton.FontColor = [0 0 0];
                % activate Pan
                app.PanToolButton.Value = 1;
                app.PanToolButton.FontColor = app.defaultHighlightColor;
                % app.panHandle = pan(app.MeasurementAxes,'on');
                app.panHandle.Enable = 'on';
            end

        end

        % Value changed function: ROIToolButton
        function ROIToolButtonValueChanged(app, event)
            value = app.ROIToolButton.Value;
            app.dcm.Enable = 'off';
            if value
                app.dcm.Enable = 'off';
                app.DistanceToolButton.FontColor = [0 0 0];
                app.DistanceToolButton.Value = 0;
                app.panHandle.Enable = 'on';
                app.ROIToolButton.FontColor = app.defaultHighlightColor;
                % prepare view to draw ROI
                % app.scatterHandle = scatter3(app.MeasurementAxes, app.measurementResultWCS.X(:), app.measurementResultWCS.Y(:), app.measurementResultWCS.Z(:), 1, app.measurementResultWCS.Z(:), 'filled');
                set(app.scatterHandle, 'XData', app.measurementResultWCS.X(:), 'YData', app.measurementResultWCS.Y(:), 'ZData', app.measurementResultWCS.Z(:), 'CData', app.measurementResultWCS.Z(:));
                % axis(app.MeasurementAxes, 'tight');
                % zlim([-1, 1]);
                app.MeasurementAxes.ZLim = [-10, 10];
                updateMeasurementAxesInfo(app);
                view(app.MeasurementAxes, 0, 90);
                app.AzimuthSlider.Enable = "off";
                app.ElevationSlider.Enable = "off";
                app.ZoomSlider.Value = 1;
                
                
                % Draw ROI and Check points inside using inpolygon
                try
                    roi = drawpolygon(app.MeasurementAxes);
                    inROI = inpolygon(app.measurementResultWCS.X(:), app.measurementResultWCS.Y(:), roi.Position(:, 1), roi.Position(:, 2));
                    delete(roi)
                    set(app.scatterHandle, 'XData', app.measurementResultWCS.X(inROI), 'YData', app.measurementResultWCS.Y(inROI), 'ZData', app.measurementResultWCS.Z(inROI), 'CData', app.measurementResultWCS.Z(inROI));
                    axis(app.MeasurementAxes, 'tight');
                catch
                    disp('error while deleting ROI Obj')
                end

                updateMeasurementAxesInfo(app);
                view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
                app.AzimuthSlider.Enable = "on";
                app.ElevationSlider.Enable = "on";
                app.panHandle.Enable = 'on';
                app.PanToolButton.FontColor = app.defaultHighlightColor;
                app.PanToolButton.Value = 1;
            else
                % restore ROI
                app.panHandle.Enable = 'on';
                app.ROIToolButton.FontColor = [0 0 0];
                app.scatterHandle = scatter3(app.MeasurementAxes, app.measurementResultWCS.X(:), app.measurementResultWCS.Y(:), app.measurementResultWCS.Z(:), 1, app.measurementResultWCS.Z(:), 'filled');
                axis(app.MeasurementAxes, 'tight');
                updateMeasurementAxesInfo(app);
                view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
                app.ZoomSlider.Value = 1;
                app.AzimuthSlider.Enable = "on";
                app.ElevationSlider.Enable = "on";
            end
            
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            clearCursorData(app)
        end

        % Button pushed function: RestoreViewButton
        function RestoreViewButtonPushed(app, event)
            app.AzimuthSlider.Value = 45;
            app.ElevationSlider.Value = 45;
            app.ZoomSlider.Value = 1;
            sliceMeasurementResults(app, app.SliceSlider.Value);
            axis(app.MeasurementAxes, 'tight');
            updateMeasurementAxesInfo(app);
            view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            sliceValue = event.Value;
            % reset Distance Tool Data
            clearCursorData(app);
            % Create a mask to filter data below threshold
            sliceMeasurementResults(app, sliceValue);
            view(app.MeasurementAxes, app.AzimuthSlider.Value, app.ElevationSlider.Value);
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)

        end

        % Value changed function: ResolutionSlider
        function ResolutionSliderValueChanged(app, event)
            value = app.ResolutionSlider.Value;
            sliceValue = app.SliceSlider.Value;
            % Percentage of points to keep (e.g., 10%)
            percentToKeep = value/100;
            numPoints = numel(app.measurementResultWCS.Z);
            numPointsToKeep = round(numPoints * percentToKeep);
            
            % Generate random indices
            app.randomIndices = randperm(numPoints, numPointsToKeep);
            sliceMeasurementResults(app, sliceValue);
            
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            view(app.MeasurementAxes, 0, 90);
            app.AzimuthSlider.Value = 0;
            app.ElevationSlider.Value = 90;
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            view(app.MeasurementAxes, 0, 0);
            app.AzimuthSlider.Value = 0;
            app.ElevationSlider.Value = 0;
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            view(app.MeasurementAxes, 90, 0);
            app.AzimuthSlider.Value = 90;
            app.ElevationSlider.Value = 0;
        end

        % Value changing function: AzimuthSlider
        function AzimuthSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.MeasurementAxes, changingValue, app.ElevationSlider.Value);
        end

        % Value changing function: ElevationSlider
        function ElevationSliderValueChanging(app, event)
            changingValue = event.Value;
            view(app.MeasurementAxes, app.AzimuthSlider.Value, changingValue);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            zoomFactor = 1/event.Value;
            newViewRangeX = app.initialViewRange.X * zoomFactor;
            zoomedXLim = 1/2*[-newViewRangeX newViewRangeX] + app.viewCenter.X;

            newViewRangeY = app.initialViewRange.Y * zoomFactor;
            zoomedYLim = 1/2*[-newViewRangeY newViewRangeY] + app.viewCenter.Y;

            newViewRangeZ = app.initialViewRange.Z * zoomFactor;
            zoomedZLim = 1/2*[-newViewRangeZ newViewRangeZ] + app.viewCenter.Z;

            app.MeasurementAxes.XLim = zoomedXLim;
            app.MeasurementAxes.YLim = zoomedYLim;
            app.MeasurementAxes.ZLim = zoomedZLim;
        end

        % Button pushed function: SelectSaveDirButton
        function SelectSaveDirButtonPushed(app, event)
            app.calibrationImagePath = uigetdir(pwd,"Select a directory to save checkerboard images.");
            % Check if user cancled the operation
            if app.calibrationImagePath == 0
                return
            end
            app.PathEditField.Value = app.calibrationImagePath;
            app.PathEditField_2.Value =  app.calibrationImagePath;
            app.StartCameraButton.Enable = "on";
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
            % Display a modal dialog indicating calculations are running
            msg = sprintf('1. Use an asymetric Checker Board.\n2. Rotate the Checker Board around all three Axes.\n3. Make sure the Patch Size is correct. \n4. Evaluate the Reprojection Errors');
            waitDialog = uiconfirm(app.UIFigure, msg, 'Record Checker Board Images for Camera Calibration', 'Options', {'Ok' 'Cancel'}, 'Icon', 'info');
        
            % Check user input
            if strcmp(waitDialog, 'Cancel')
                % User clicked Cancel, handle accordingly (optional)
                disp('User cancled image recording for camera calibration')
                return;
            else
                disp('User started image recording for camera calibration');
                % start preview on calibration image axes
                hImage = image(app.CalibrationImagesAxes, zeros(200, 300, 1, 'uint8'));
                app.previewHandle = preview(app.camera,hImage);
                app.CalibrationImagesAxes.XLim = [0, app.cameraSize(1)];
                app.CalibrationImagesAxes.YLim = [0, app.cameraSize(2)];
                app.SnapButton.Enable = "on";
                app.StartCameraButton.Enable = "off";
                app.LoadImagesButton.Enable = "off";
            end
        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            % take a snapshot
            A = app.recentFrame;
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd_HHmmss');
            % optionaly convert to grayscale
            % A = rgb2gray(A);
            filename = fullfile(app.calibrationImagePath, ['\' char(timestamp) '.bmp']);
            disp(filename)
            imwrite(A,filename);

            % Load and show
            loadAndShowCalibrationImages(app);
            app.CalibrateCameraButton.Enable = "on";
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            app.calibrationImagePath = uigetdir(pwd,"Select Checker Board Images");
            % Check if user cancled the operation
            if app.calibrationImagePath == 0
                return
            end
            app.PathEditField.Value = app.calibrationImagePath;
            
            % Load and show images
            loadAndShowCalibrationImages(app);

            % calibrateCamera
            calibrateCamera(app);
            updateCalibrationImagesAxes(app, 1)
            app.LoadedListBox.Enable = "on";
        end

        % Button pushed function: CalibrateCameraButton
        function CalibrateCameraButtonPushed(app, event)
            delete(app.previewHandle);
            app.SnapButton.Enable = "off";
            app.StartCameraButton.Enable = "on";
            app.LoadImagesButton.Enable = "on";
            try
                calibrateCamera(app);
                updateCalibrationImagesAxes(app, 1)
            catch
                msg = 'Calibration failed, please try again.';
                uiconfirm(app.UIFigure, msg, 'Camera Calibration Error', 'Options', {'Ok'}, 'Icon', 'error');
            end
            app.LoadedListBox.Enable = "on";
        end

        % Value changed function: LoadedListBox
        function LoadedListBoxValueChanged(app, event)
            value = app.LoadedListBox.Value;
            % Get the index of the selected item
            k = find(strcmp(app.LoadedListBox.Items, value));
            app.selectedImageIndex = k;
            updateCalibrationImagesAxes(app, k)
        end

        % Value changed function: UndistortButton
        function UndistortButtonValueChanged(app, event)
            value = app.UndistortButton.Value;
            k = app.selectedImageIndex;
            if value
                % show undistorted image
                selectedImage = app.imgs_correct{k};
                image_points = app.batch_img_pts(:,:,k);
            else
                % show distorted image
                selectedImage = app.images{k};
                image_points = app.batch_img_pts(:,:,k);
            end
            selectedImage = insertMarker(selectedImage, image_points, 'o', 'Color', 'red', 'Size', 20);
            % selectedImage = insertText(selectedImage,image_points,1:size(image_points,1));
            imshow(selectedImage, 'Parent', app.CalibrationImagesAxes);  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop and delete timer
            
            close all
            % if isvalid(app.livePreviewTimerObj)
            %     stop(app.livePreviewTimerObj);
            %     delete(app.livePreviewTimerObj);
            % end

            if ~isempty(app.camera) && isvalid(app.camera)
                stop(app.camera);
                delete(app.camera);
                clear app.camera;
            end
            delete(imaqfind);
            delete(app)
        end

        % Button pushed function: StartProcessButton
        function StartProcessButtonPushed(app, event)
            % 0. setup \tmp folder 
            folderName = [app.rootDir app.tmpFolder_telecentricity '\']; % Specify the name of the folder
            logMessage(app, 'starting telecentricity error evaluation...');
            tic
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Recording Images', 'Message', 'Image acquisitions are in progress...');
            pause(1)
            % Check if the folder already exists
            if ~exist(folderName, 'dir')
                % If the folder does not exist, create it
                mkdir(folderName);
                disp(['Folder "' folderName '" created successfully.']);
            else
                % remove all files and folder and create new
                rmdir(folderName, 's');
                disp(['Folder "' folderName '" already exists. I will delete it now and create an empty one']);
                mkdir(folderName);
                disp(['Empty folder "' folderName '" created successfully.']);
            end
            % 1. start camera
            startCamera(app);
            pause(1)
            % 2. project dark frame
            frame = uint8(ones(flip(app.projectorSize)) * 0);
            set(app.projectorImageHandle, 'CData', frame);
            % 3. start a for loop
            app.stage.motionType = 1; % 0: G90=absolut, 1: G91 Motion = relative,
            app.stage.motionDistance = -4.5;
            % Move stage and wait for motion to be finished
            sendDataToStageController(app);
            pause(10);
            % Set step size to 1 mm
            app.stage.motionDistance = 1;
            for k=1:9
                dlg.Value = k/9;
                filename = [folderName '\' num2str(k) '.bmp'];
                imwrite(app.recentFrame, filename);
                % send movement request
                sendDataToStageController(app);
                % wait for motion to be finished
                pause(3);
            end
            % move back to z=0
            app.stage.motionDistance = -4.5;
            sendDataToStageController(app);
            stopCamera(app);
            delete(dlg)
            elapsedTime = toc;
            logMessage(app, ['operation done. elapsed time: ' num2str(elapsedTime) 's']);

            % calculate telecentricity error
            tic
            logMessage(app, 'telecentricity error calculations started...');
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Calculating', 'Message', 'Looking for key points and calculating telecentricity error...');

            telecError = evaluate_telecentricity_error(app, folderName, dlg);
            % show result
            app.TotaltelecentricityerrorEditField.Value = [num2str(telecError) ' %'];
            app.PhaseCalibrationAxesC1.Visible = "on";
            app.PhaseCalibrationAxesC1.DataAspectRatio = [1 1 1];
            app.PhaseCalibrationAxesC1.XLim = [0 app.cameraSize(1)];
            app.PhaseCalibrationAxesC1.YLim = [0 app.cameraSize(2)];
            app.PhaseCalibrationAxesC1.Colormap = gray;
            app.PhaseCalibrationAxesC1.Title.Color = [0.902 0.902 0.902];
            app.PhaseCalibrationAxesC1.Title.String = "Telecentricity error over \Deltaz = 9 mm";
            app.PhaseCalibrationAxesC1.Title.Interpreter = 'tex';

            % Close dialogue
            delete(dlg)
            elapsedTime = toc;
            logMessage(app, ['operation done. elapsed time: ' num2str(elapsedTime) 's']);
        end

        % Button pushed function: StartPhaseCalibrationButton
        function StartPhaseCalibrationButtonPushed(app, event)
            logMessage(app, 'starting phase to height calibration process...');
            tic
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Recording Images', 'Message', 'Image acquisitions are in progress...');
            
            delta_z = 0.2;
            z_vec = 0:delta_z:4;
            calibrationMeasurementData = NaN(app.cameraSize(2), app.cameraSize(1), length(z_vec));
            
            app.stage.motionType = 1; % 0: G90=absolut, 1: G91 Motion = relative,
            app.stage.motionDistance = - max(z_vec)/2;
            sendDataToStageController(app);
            % 2. wait for movement completed
            pause(5);
            % 1. record z stack Phase data 
            startCamera(app);
            pause(1)
            
            for k=1:length(z_vec)
                dlg.Value = k/length(z_vec);
                % 0. measure
                calibrationMeasurementData(:,:,k) = perform_heterodyne_calibration_measurement(app);
                % 1. move stage
                app.stage.motionDistance = delta_z;
                sendDataToStageController(app);
                % 2. wait for movement completed
                pause(3);
            end
            % move back to zero
            app.stage.motionDistance = - max(z_vec)/2;
            sendDataToStageController(app);
            % 2. wait for movement completed
            pause(3);
            stopCamera(app);

            delete(dlg)
            elapsedTime = toc;
            logMessage(app, ['operation done. elapsed time: ' num2str(elapsedTime) 's']);
            tic
            logMessage(app, 'phase to height calibration calculations started...');
            dlg = uiprogressdlg(app.UIFigure, 'Title', 'Calculating', 'Message', 'Calculating linear fit coefficients for each pixel...');

            % filename = [app.rootDir '\tmp\calibrationMeasurementData.mat'];
            % calibrationMeasurementData = load(filename);
            % calibrationMeasurementData = calibrationMeasurementData.linFit;
            % 2. perform phase to height mapping
            linFit = phase_to_height_calibration(calibrationMeasurementData, z_vec, dlg);
            % overwrite Phase Calibration Data
            app.phaseToHeigthFit = linFit;
            % 3. Save Calibration Data
            % save('linFitResult.mat', 'linFit');
            
            % 4. Show Linear fit coefficients
            app.PhaseCalibrationAxesC1.Title.String = "Coeffs(1): slope";
            app.PhaseCalibrationAxesC2.Title.String = "Coeffs(2): y-intercept";
            app.PhaseCalibrationAxesC1.Title.Color = [0.902 0.902 0.902];
            app.PhaseCalibrationAxesC2.Title.Color = [0.902 0.902 0.902];
            app.PhaseCalibrationAxesC1.Visible = "on";
            app.PhaseCalibrationAxesC2.Visible = "on";
        
            imagesc(app.PhaseCalibrationAxesC1, linFit(:,:,1))
            imagesc(app.PhaseCalibrationAxesC2, linFit(:,:,2))

            app.PhaseCalibrationAxesC1.DataAspectRatio = [1 1 1];
            app.PhaseCalibrationAxesC2.DataAspectRatio = [1 1 1];

            app.PhaseCalibrationAxesC1.XLim = [0 size(linFit,2)];
            app.PhaseCalibrationAxesC1.YLim = [0 size(linFit,1)];

            app.PhaseCalibrationAxesC2.XLim = [0 size(linFit,2)];
            app.PhaseCalibrationAxesC2.YLim = [0 size(linFit,1)];
            
            app.PhaseCalibrationAxesC1.Box = "off";
            app.PhaseCalibrationAxesC2.Box = "off";

            app.PhaseCalibrationAxesC1.Colormap = jet;
            app.PhaseCalibrationAxesC2.Colormap = jet;

            elapsedTime = toc;
            logMessage(app, ['operation done. elapsed time: ' num2str(elapsedTime) 's']);
            delete(dlg)
        end

        % Button pushed function: SnapShotButton
        function SnapShotButtonPushed(app, event)
            % save current Image to file
            userProfile = getenv('USERPROFILE');
            picturesFolder = fullfile(userProfile, 'Pictures');
            folderName = [picturesFolder '\ProMakroS'];
            % Check if the folder already exists
            if ~exist(folderName, 'dir')
                % If the folder does not exist, create it
                mkdir(folderName);
                disp(['Folder "' folderName '" created successfully.']);
            end
            % take a snapshot
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd_HHmmss');
            filename = fullfile(folderName, ['\' char(timestamp) '.bmp']);
            disp(filename)
            imwrite(app.recentFrame,filename);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LiveImageTab = uitab(app.TabGroup);
            app.LiveImageTab.Title = 'Live Image';
            app.LiveImageAxes = uiaxes(app.LiveImageTab);
            app.SnapShotButton = uibutton(app.LiveImageTab, 'push');
            app.SnapShotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapShotButtonPushed, true);
            app.SnapShotButton.Tooltip = {'Save current image to "Pictures" folder'};
            app.SnapShotButton.Text = 'Snap Shot';
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.MeasurementAxes = uiaxes(app.MeasurementTab);
            xlabel(app.MeasurementAxes, 'x [mm]')
            ylabel(app.MeasurementAxes, 'y [mm]')
            zlabel(app.MeasurementAxes, 'z [mm]')
            colormap(app.MeasurementAxes, 'jet')
            app.AzimuthLabel = uilabel(app.MeasurementTab);
            app.AzimuthLabel.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.MeasurementTab);
            app.AzimuthSlider.Limits = [0 360];
            app.AzimuthSlider.ValueChangingFcn = createCallbackFcn(app, @AzimuthSliderValueChanging, true);
            app.AzimuthSlider.Value = 45;
            app.ElevationLabel = uilabel(app.MeasurementTab);
            app.ElevationLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.MeasurementTab);
            app.ElevationSlider.Limits = [-90 90];
            app.ElevationSlider.ValueChangingFcn = createCallbackFcn(app, @ElevationSliderValueChanging, true);
            app.ElevationSlider.Value = 45;
            app.ZoomSliderLabel = uilabel(app.MeasurementTab);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.MeasurementTab);
            app.ZoomSlider.Limits = [0.5 10];
            app.ZoomSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.Value = 1;
            app.SliceSliderLabel = uilabel(app.MeasurementTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.MeasurementTab);
            app.SliceSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SliceSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.SliceSlider.Value = 100;
            app.XYButton = uibutton(app.MeasurementTab, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'XY';
            app.XZButton = uibutton(app.MeasurementTab, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'XZ';
            app.YZButton = uibutton(app.MeasurementTab, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'YZ';
            app.DistanceToolButton = uibutton(app.MeasurementTab, 'state');
            app.DistanceToolButton.ValueChangedFcn = createCallbackFcn(app, @DistanceToolButtonValueChanged, true);
            app.DistanceToolButton.Text = {'Distance'; 'Tool'};
            app.PanToolButton = uibutton(app.MeasurementTab, 'state');
            app.PanToolButton.ValueChangedFcn = createCallbackFcn(app, @PanToolButtonValueChanged, true);
            app.PanToolButton.Text = {'Pan'; 'Tool'};
            app.PanToolButton.Value = true;
            app.ClearDataButton = uibutton(app.MeasurementTab, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = {'Clear'; 'Data'};
            app.RestoreViewButton = uibutton(app.MeasurementTab, 'push');
            app.RestoreViewButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreViewButtonPushed, true);
            app.RestoreViewButton.Text = {'Restore'; 'View'};
            app.ROIToolButton = uibutton(app.MeasurementTab, 'state');
            app.ROIToolButton.ValueChangedFcn = createCallbackFcn(app, @ROIToolButtonValueChanged, true);
            app.ROIToolButton.Text = {'ROI'; 'Tool'};
            app.ResolutionSliderLabel = uilabel(app.MeasurementTab);
            app.ResolutionSliderLabel.Text = 'Resolution';
            app.ResolutionSlider = uislider(app.MeasurementTab);
            app.ResolutionSlider.Limits = [1 100];
            app.ResolutionSlider.MajorTicks = [1 20 40 60 80 100];
            app.ResolutionSlider.MajorTickLabels = {'1', '20', '40', '60', '80', '100'};
            app.ResolutionSlider.ValueChangedFcn = createCallbackFcn(app, @ResolutionSliderValueChanged, true);
            app.ResolutionSlider.MinorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.ResolutionSlider.Value = 100;
            app.CameraCalibrationTab = uitab(app.TabGroup);
            app.CameraCalibrationTab.Title = 'Camera Calibration';
            app.CalibrationImagesAxes = uiaxes(app.CameraCalibrationTab);
            app.CalibrationImagesAxes.Visible = 'off';
            app.LoadedCalibrationImagesPanel = uipanel(app.CameraCalibrationTab);
            app.LoadedCalibrationImagesPanel.Title = 'Loaded Calibration Images';
            app.LoadCheckerBoardImagesPanel = uipanel(app.CameraCalibrationTab);
            app.LoadCheckerBoardImagesPanel.Title = '2. Load Checker Board Images';
            app.LoadImagesButton = uibutton(app.LoadCheckerBoardImagesPanel, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.PathEditFieldLabel = uilabel(app.LoadCheckerBoardImagesPanel);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.LoadCheckerBoardImagesPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Enable = 'off';
            app.LoadedListBoxLabel = uilabel(app.LoadCheckerBoardImagesPanel);
            app.LoadedListBoxLabel.Enable = 'off';
            app.LoadedListBoxLabel.Text = 'Loaded ';
            app.LoadedListBox = uilistbox(app.LoadCheckerBoardImagesPanel);
            app.LoadedListBox.Items = {};
            app.LoadedListBox.ValueChangedFcn = createCallbackFcn(app, @LoadedListBoxValueChanged, true);
            app.LoadedListBox.Enable = 'off';
            app.LoadedListBox.Tooltip = {'Click to preview an image'};
            app.LoadedListBox.Value = {};
            app.CalibrateCameraButton = uibutton(app.LoadCheckerBoardImagesPanel, 'push');
            app.CalibrateCameraButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateCameraButtonPushed, true);
            app.CalibrateCameraButton.Enable = 'off';
            app.CalibrateCameraButton.Text = 'Calibrate Camera';
            app.RecordnewCheckerBoardImagesPanel = uipanel(app.CameraCalibrationTab);
            app.RecordnewCheckerBoardImagesPanel.Title = '1. Record new Checker Board Images';
            app.StartCameraButton = uibutton(app.RecordnewCheckerBoardImagesPanel, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Enable = 'off';
            app.StartCameraButton.Text = 'Start Camera';
            app.SelectSaveDirButton = uibutton(app.RecordnewCheckerBoardImagesPanel, 'push');
            app.SelectSaveDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveDirButtonPushed, true);
            app.SelectSaveDirButton.Text = 'Select Save Dir';
            app.PathEditField_2Label = uilabel(app.RecordnewCheckerBoardImagesPanel);
            app.PathEditField_2Label.Text = 'Path:';
            app.PathEditField_2 = uieditfield(app.RecordnewCheckerBoardImagesPanel, 'text');
            app.PathEditField_2.Editable = 'off';
            app.PathEditField_2.Enable = 'off';
            app.PathEditField_2.Tooltip = {'path to save images'};
            app.PatchSizeinmmEditField_2Label = uilabel(app.RecordnewCheckerBoardImagesPanel);
            app.PatchSizeinmmEditField_2Label.Text = 'Patch Size in mm';
            app.PatchSizeEditField = uieditfield(app.RecordnewCheckerBoardImagesPanel, 'numeric');
            app.PatchSizeEditField.Limits = [0 40];
            app.PatchSizeEditField.ValueDisplayFormat = '%.2f';
            app.PatchSizeEditField.Value = 3;
            app.SnapButton = uibutton(app.RecordnewCheckerBoardImagesPanel, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Enable = 'off';
            app.SnapButton.Text = 'Snap';
            app.CalibrationResultsPanel = uipanel(app.CameraCalibrationTab);
            app.CalibrationResultsPanel.Title = 'Calibration Results';
            app.KameraMatrixLabel = uilabel(app.CalibrationResultsPanel);
            app.KameraMatrixLabel.Interpreter = 'latex';
            app.KameraMatrixLabel.Tooltip = {'camera intrinsics'};
            app.KameraMatrixLabel.Text = '$K=\left(\matrix{\alpha & \gamma & 0 \cr 0 & \beta & 0 \cr 0 & 0 & 0}\right)$';
            app.CalibrationStatsLabel = uilabel(app.CalibrationResultsPanel);
            app.CalibrationStatsLabel.Interpreter = 'latex';
            app.CalibrationStatsLabel.Tooltip = {'mean reprojection error of all images [pixel]'};
            app.CalibrationStatsLabel.Text = '$\varepsilon_{mean} = $';
            app.ExtrinsicMatrixLabel = uilabel(app.CalibrationResultsPanel);
            app.ExtrinsicMatrixLabel.Interpreter = 'latex';
            app.ExtrinsicMatrixLabel.Tooltip = {'camera extrinsics'};
            app.ExtrinsicMatrixLabel.Text = '$rot=\left(\matrix{\Phi \cr \Psi \cr \Theta }\right),$   $t=\left(\matrix{t_x \cr t_y \cr 0}\right)$';
            app.SelectedCalibrationStatsLabel = uilabel(app.CameraCalibrationTab);
            app.SelectedCalibrationStatsLabel.Interpreter = 'latex';
            app.SelectedCalibrationStatsLabel.Text = 'Reprojection Error: $\varepsilon_{i} = $';
            app.UndistortButton = uibutton(app.CameraCalibrationTab, 'state');
            app.UndistortButton.ValueChangedFcn = createCallbackFcn(app, @UndistortButtonValueChanged, true);
            app.UndistortButton.Text = 'Undistort';
            app.SystemCalibrationTab = uitab(app.TabGroup);
            app.SystemCalibrationTab.Title = 'System Calibration';
            app.PhaseCalibrationAxesC1 = uiaxes(app.SystemCalibrationTab);
            title(app.PhaseCalibrationAxesC1, 'coeffs(1)')
            app.PhaseCalibrationAxesC1.Visible = 'off';
            colormap(app.PhaseCalibrationAxesC1, 'jet')
            app.PhaseCalibrationAxesC2 = uiaxes(app.SystemCalibrationTab);
            title(app.PhaseCalibrationAxesC2, 'coeffs(2)')
            app.PhaseCalibrationAxesC2.Visible = 'off';
            colormap(app.PhaseCalibrationAxesC2, 'jet')
            app.EvaluateTelecentricityErrorPanel = uipanel(app.SystemCalibrationTab);
            app.EvaluateTelecentricityErrorPanel.Title = 'Evaluate Telecentricity Error';
            app.StartProcessButton = uibutton(app.EvaluateTelecentricityErrorPanel, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessButtonPushed, true);
            app.StartProcessButton.Text = 'Start Process';
            app.TotaltelecentricityerrorEditFieldLabel = uilabel(app.EvaluateTelecentricityErrorPanel);
            app.TotaltelecentricityerrorEditFieldLabel.Text = 'Total telecentricity error:';
            app.TotaltelecentricityerrorEditField = uieditfield(app.EvaluateTelecentricityErrorPanel, 'text');
            app.TotaltelecentricityerrorEditField.Editable = 'off';
            app.TotaltelecentricityerrorEditField.Enable = 'off';
            app.TotaltelecentricityerrorEditField.Tooltip = {''};
            app.Usethesamecheckerboardasforcameracalibration2Label = uilabel(app.EvaluateTelecentricityErrorPanel);
            app.Usethesamecheckerboardasforcameracalibration2Label.Text = {'1. Use the same checker board as for camera calibration.'; ''; '2. Position the object at the center of the depth of field range. '; '    The stage will move from -4.5 mm to +4.5 mm around this position.'; ''; '3. Images will be stored in the /tmp-folder and deleted after the application is '; '    terminated'};
            app.GeneratenewPhasetoHeightCalibrationFilePanel = uipanel(app.SystemCalibrationTab);
            app.GeneratenewPhasetoHeightCalibrationFilePanel.Title = 'Generate new Phase-to-Height Calibration File';
            app.StartPhaseCalibrationButton = uibutton(app.GeneratenewPhasetoHeightCalibrationFilePanel, 'push');
            app.StartPhaseCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartPhaseCalibrationButtonPushed, true);
            app.StartPhaseCalibrationButton.Text = 'Start Phase Calibration';
            app.Usethesamecheckerboardasforcameracalibration2Label_2 = uilabel(app.GeneratenewPhasetoHeightCalibrationFilePanel);
            app.Usethesamecheckerboardasforcameracalibration2Label_2.Text = {'1. Use a flat object, preferably white with a rough surface.'; ''; '2. Position the object at the center of the depth of field range, ensuring that the '; '    fringe contrast is maximized. The stage will move from -2 mm to +2 mm around '; '    this position.'; ''; '3. Measurements will be stored in the /tmp-folder and deleted after the application '; '    is terminated. '; ''; '4. Caution: Calibration settings will be overwritten.'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.HeterodyneFringePatternPanel = uipanel(app.SettingsTab);
            app.HeterodyneFringePatternPanel.Title = 'Heterodyne Fringe Pattern';
            app.lambda_1Label = uilabel(app.HeterodyneFringePatternPanel);
            app.lambda_1Label.Interpreter = 'latex';
            app.lambda_1Label.Text = '$\lambda_1$:';
            app.lambda_1EditField = uieditfield(app.HeterodyneFringePatternPanel, 'numeric');
            app.lambda_1EditField.Limits = [0 100];
            app.lambda_1EditField.Value = 20;
            app.lambda_2EditFieldLabel = uilabel(app.HeterodyneFringePatternPanel);
            app.lambda_2EditFieldLabel.Interpreter = 'latex';
            app.lambda_2EditFieldLabel.Text = '$\lambda_2$:';
            app.lambda_2EditField = uieditfield(app.HeterodyneFringePatternPanel, 'numeric');
            app.lambda_2EditField.Limits = [0 100];
            app.lambda_2EditField.Value = 21.581;
            app.lambda_3EditFieldLabel = uilabel(app.HeterodyneFringePatternPanel);
            app.lambda_3EditFieldLabel.Interpreter = 'latex';
            app.lambda_3EditFieldLabel.Text = '$\lambda_3$:';
            app.lambda_3EditField = uieditfield(app.HeterodyneFringePatternPanel, 'numeric');
            app.lambda_3EditField.Limits = [0 100];
            app.lambda_3EditField.Value = 23.162;
            app.projectiondelaysEditFieldLabel = uilabel(app.HeterodyneFringePatternPanel);
            app.projectiondelaysEditFieldLabel.Text = 'projection delay [s]:';
            app.projectiondelaysEditField = uieditfield(app.HeterodyneFringePatternPanel, 'numeric');
            app.projectiondelaysEditField.Limits = [0 100];
            app.projectiondelaysEditField.Value = 0.3;
            app.LoadSettingsButton = uibutton(app.SettingsTab, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.SettingsTab, 'push');
            app.SaveSettingsButton.Text = 'Save Settings';
            app.CameraIntrinsicParametersPanel = uipanel(app.SettingsTab);
            app.CameraIntrinsicParametersPanel.Title = 'Camera Intrinsic Parameters';
            app.gammaEditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.gammaEditFieldLabel.Text = 'gamma';
            app.gammaEditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.gammaEditField.Limits = [0 100];
            app.gammaEditField.Editable = 'off';
            app.gammaEditField.Value = 0.3;
            app.u0EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.u0EditFieldLabel.Text = 'u0';
            app.u0EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.u0EditField.Limits = [0 100];
            app.u0EditField.Editable = 'off';
            app.u0EditField.Value = 0.3;
            app.v0EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.v0EditFieldLabel.Text = 'v0';
            app.v0EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.v0EditField.Limits = [0 100];
            app.v0EditField.Editable = 'off';
            app.v0EditField.Value = 0.3;
            app.k1EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.k1EditField.Limits = [0 100];
            app.k1EditField.Editable = 'off';
            app.k1EditField.Value = 0.3;
            app.alphaEditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.alphaEditField.Limits = [0 100];
            app.alphaEditField.Editable = 'off';
            app.alphaEditField.Value = 0.3;
            app.betaEditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.betaEditField.Limits = [0 100];
            app.betaEditField.Editable = 'off';
            app.betaEditField.Value = 0.3;
            app.LensscalingfactorsLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.LensscalingfactorsLabel.Text = 'Lens scaling factors';
            app.LensskewfactorLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.LensskewfactorLabel.Text = 'Lens skew factor';
            app.LensdistortioncenterLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.LensdistortioncenterLabel.Text = 'Lens distortion center';
            app.LensdistortioncoefficientsLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.LensdistortioncoefficientsLabel.Text = 'Lens distortion coefficients';
            app.p1EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.p1EditField.Limits = [0 100];
            app.p1EditField.Editable = 'off';
            app.p1EditField.Value = 0.3;
            app.p2EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.p2EditField.Limits = [0 100];
            app.p2EditField.Editable = 'off';
            app.p2EditField.Value = 0.3;
            app.q1EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.q1EditField.Limits = [0 100];
            app.q1EditField.Editable = 'off';
            app.q1EditField.Value = 0.3;
            app.q2EditFieldLabel = uilabel(app.CameraIntrinsicParametersPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.CameraIntrinsicParametersPanel, 'numeric');
            app.q2EditField.Limits = [0 100];
            app.q2EditField.Editable = 'off';
            app.q2EditField.Value = 0.3;
            app.SystemLogTab = uitab(app.TabGroup);
            app.SystemLogTab.Title = 'System Log';
            app.LogTextArea = uitextarea(app.SystemLogTab);
            app.LogTextArea.Placeholder = 'logs...';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Hyperlink = uihyperlink(app.HelpTab);
            app.Hyperlink.URL = 'https://github.com/algroege/PMS_FringeProjection';
            app.Hyperlink.Text = 'Visit Repository on GitHub';
            app.amgopticsLabel = uilabel(app.UIFigure);
            app.amgopticsLabel.Text = '© 2024 amg-optics';
            app.MainSystemControlsPanel = uipanel(app.UIFigure);
            app.MainSystemControlsPanel.Title = 'Main System Controls';
            app.StageControlPanel = uipanel(app.MainSystemControlsPanel);
            app.StageControlPanel.Title = 'Stage Control';
            app.ZButtonUp = uibutton(app.StageControlPanel, 'push');
            app.ZButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ZButtonUpPushed, true);
            app.ZButtonUp.Enable = 'off';
            app.ZButtonUp.Text = '+Z';
            app.ZButtonDown = uibutton(app.StageControlPanel, 'push');
            app.ZButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ZButtonDownPushed, true);
            app.ZButtonDown.Enable = 'off';
            app.ZButtonDown.Text = '-Z';
            app.PositionGaugeLabel = uilabel(app.StageControlPanel);
            app.PositionGaugeLabel.Enable = 'off';
            app.PositionGaugeLabel.Text = 'Position';
            app.PositionGauge = uigauge(app.StageControlPanel, 'linear');
            app.PositionGauge.Enable = 'off';
            app.ComPortDropDownLabel = uilabel(app.StageControlPanel);
            app.ComPortDropDownLabel.Text = 'Com Port';
            app.ComPortDropDown = uidropdown(app.StageControlPanel);
            app.ComPortDropDown.Items = {};
            app.ComPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ComPortDropDownOpening, true);
            app.ComPortDropDown.ClickedFcn = createCallbackFcn(app, @ComPortDropDownClicked, true);
            app.ComPortDropDown.Value = {};
            app.StepSizemmDropDownLabel = uilabel(app.StageControlPanel);
            app.StepSizemmDropDownLabel.Enable = 'off';
            app.StepSizemmDropDownLabel.Text = 'Step Size [mm]';
            app.StepSizemmDropDown = uidropdown(app.StageControlPanel);
            app.StepSizemmDropDown.Items = {'0.1', '0.5', '1', '2'};
            app.StepSizemmDropDown.Enable = 'off';
            app.StepSizemmDropDown.Value = '1';
            app.StageStatusLabel = uilabel(app.StageControlPanel);
            app.StageStatusLabel.Text = 'Offline';
            app.StageStatusLamp = uilamp(app.StageControlPanel);
            app.CameraSettingsPanel = uipanel(app.MainSystemControlsPanel);
            app.CameraSettingsPanel.Title = 'Camera Settings';
            app.ExposureTimemsSpinnerLabel = uilabel(app.CameraSettingsPanel);
            app.ExposureTimemsSpinnerLabel.Text = 'Exposure Time [ms]';
            app.ExposureTimemsSpinner = uispinner(app.CameraSettingsPanel);
            app.ExposureTimemsSpinner.Step = 10;
            app.ExposureTimemsSpinner.Limits = [1 1000];
            app.ExposureTimemsSpinner.ValueChangedFcn = createCallbackFcn(app, @ExposureTimemsSpinnerValueChanged, true);
            app.ExposureTimemsSpinner.Value = 15;
            app.CameraStatusLamp = uilamp(app.CameraSettingsPanel);
            app.CameraStatusLabel = uilabel(app.CameraSettingsPanel);
            app.CameraStatusLabel.Text = 'Offline';
            app.MeasurementPanel = uipanel(app.MainSystemControlsPanel);
            app.MeasurementPanel.Title = 'Measurement';
            app.StartMeasurementButton = uibutton(app.MeasurementPanel, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.ExportPointcloudButton = uibutton(app.MeasurementPanel, 'push');
            app.ExportPointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPointcloudButtonPushed, true);
            app.ExportPointcloudButton.Enable = 'off';
            app.ExportPointcloudButton.Text = 'Export Pointcloud';
            app.ProjectorPanel = uipanel(app.MainSystemControlsPanel);
            app.ProjectorPanel.Title = 'Projector';
            app.redButton = uibutton(app.ProjectorPanel, 'state');
            app.redButton.ValueChangedFcn = createCallbackFcn(app, @redButtonValueChanged, true);
            app.redButton.Enable = 'off';
            app.redButton.Text = 'red';
            app.greenButton = uibutton(app.ProjectorPanel, 'state');
            app.greenButton.ValueChangedFcn = createCallbackFcn(app, @greenButtonValueChanged, true);
            app.greenButton.Enable = 'off';
            app.greenButton.Text = 'green';
            app.blueButton = uibutton(app.ProjectorPanel, 'state');
            app.blueButton.ValueChangedFcn = createCallbackFcn(app, @blueButtonValueChanged, true);
            app.blueButton.Enable = 'off';
            app.blueButton.Text = 'blue';
            app.ProjectorStatusLamp = uilamp(app.ProjectorPanel);
            app.ProjectorStatusLabel = uilabel(app.ProjectorPanel);
            app.ProjectorStatusLabel.Text = 'Offline';
            app.whiteButton = uibutton(app.ProjectorPanel, 'push');
            app.whiteButton.ButtonPushedFcn = createCallbackFcn(app, @whiteButtonPushed, true);
            app.whiteButton.Enable = 'off';
            app.whiteButton.Text = 'white';
            app.fringesButton = uibutton(app.ProjectorPanel, 'push');
            app.fringesButton.ButtonPushedFcn = createCallbackFcn(app, @fringesButtonPushed, true);
            app.fringesButton.Enable = 'off';
            app.fringesButton.Text = 'fringes';
            app.plainButton = uibutton(app.ProjectorPanel, 'push');
            app.plainButton.ButtonPushedFcn = createCallbackFcn(app, @plainButtonPushed, true);
            app.plainButton.Enable = 'off';
            app.plainButton.Text = 'plain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProMakroS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end